classdef A2app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UserDropDown                matlab.ui.control.DropDown
        UserDropDownLabel           matlab.ui.control.Label
        PlanarmovementsLabel        matlab.ui.control.Label
        JointmovementsLabel         matlab.ui.control.Label
        ZSlider                     matlab.ui.control.Slider
        ZSliderLabel                matlab.ui.control.Label
        YSlider                     matlab.ui.control.Slider
        YSliderLabel                matlab.ui.control.Label
        XSlider                     matlab.ui.control.Slider
        XSliderLabel                matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        LampLabel                   matlab.ui.control.Label
        WhenthelightisgreenyoucanchangethepositionoftherobotLabel  matlab.ui.control.Label
        Joint4Slider                matlab.ui.control.Slider
        Joint4SliderLabel           matlab.ui.control.Label
        Joint3Slider                matlab.ui.control.Slider
        Joint3SliderLabel           matlab.ui.control.Label
        Joint2Slider                matlab.ui.control.Slider
        Joint2SliderLabel           matlab.ui.control.Label
        Joint1Slider                matlab.ui.control.Slider
        Joint1SliderLabel           matlab.ui.control.Label
        ESTOPButton                 matlab.ui.control.Button
        E5FCC2Label                 matlab.ui.control.Label
        F4FLabel                    matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        de0adLabel                  matlab.ui.control.Label
        ADA8Label                   matlab.ui.control.Label
        IfyesenternewamountsLabel   matlab.ui.control.Label
        NOButton                    matlab.ui.control.Button
        YESButton                   matlab.ui.control.Button
        noCheckBox                  matlab.ui.control.CheckBox
        yesCheckBox                 matlab.ui.control.CheckBox
        name2CheckBox               matlab.ui.control.CheckBox
        name1CheckBox               matlab.ui.control.CheckBox
        DoyouwishtochangethedoasgetakenLabel  matlab.ui.control.Label
        ChoseyournameLabel          matlab.ui.control.Label
        Med3EditField               matlab.ui.control.NumericEditField
        Med3EditFieldLabel          matlab.ui.control.Label
        Med2EditField               matlab.ui.control.NumericEditField
        Med2EditFieldLabel          matlab.ui.control.Label
        Med1EditField               matlab.ui.control.NumericEditField
        Med1EditFieldLabel          matlab.ui.control.Label
        WelcomeLabel                matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        TimeforsomemedicationLabel  matlab.ui.control.Label
        ContextMenu                 matlab.ui.container.ContextMenu
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
    end


    properties (Access = private)
        ESTOPState = true;
        user;
        
        % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: YESButton
        function YESButtonPushed(app, event)
            app.yesCheckBox.Value = 1;
        end

        % Button pushed function: NOButton
        function NOButtonPushed(app, event)
            app.noCheckBox.Value = 1;
        end

        % Value changed function: UserDropDown
        function UserDropDownValueChanged(app, event)
            app.user = app.UserDropDown.Value;
            app.UserDropDown.Value
             if app.user == app.StephButton.Value
                % insert code here
                % if mode 1 --> do this
            elseif app.user == app.HannahButton.Value
                %insert code here
                % if mode 2 --> do this

            end
            %  Insert code as to what happens if Hannah or Steph is chosen
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            app.ESTOPState = false; 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeforsomemedicationLabel = uilabel(app.UIFigure);
            app.TimeforsomemedicationLabel.Text = {'  Time for some medication'; ''};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.WelcomeLabel = uilabel(app.UIFigure);
            app.WelcomeLabel.Text = 'Welcome';
            app.Med1EditFieldLabel = uilabel(app.UIFigure);
            app.Med1EditFieldLabel.Text = 'Med 1';
            app.Med1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Med2EditFieldLabel = uilabel(app.UIFigure);
            app.Med2EditFieldLabel.Text = 'Med 2';
            app.Med2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Med3EditFieldLabel = uilabel(app.UIFigure);
            app.Med3EditFieldLabel.Text = 'Med 3';
            app.Med3EditField = uieditfield(app.UIFigure, 'numeric');
            app.ChoseyournameLabel = uilabel(app.UIFigure);
            app.ChoseyournameLabel.Text = 'Chose your name:';
            app.DoyouwishtochangethedoasgetakenLabel = uilabel(app.UIFigure);
            app.DoyouwishtochangethedoasgetakenLabel.Text = 'Do you wish to change the doasge taken?';
            app.name1CheckBox = uicheckbox(app.UIFigure);
            app.name1CheckBox.Text = 'name1';
            app.name2CheckBox = uicheckbox(app.UIFigure);
            app.name2CheckBox.Text = 'name2';
            app.yesCheckBox = uicheckbox(app.UIFigure);
            app.yesCheckBox.Text = 'yes';
            app.noCheckBox = uicheckbox(app.UIFigure);
            app.noCheckBox.Text = 'no';
            app.YESButton = uibutton(app.UIFigure, 'push');
            app.YESButton.ButtonPushedFcn = createCallbackFcn(app, @YESButtonPushed, true);
            app.YESButton.Text = 'YES';
            app.NOButton = uibutton(app.UIFigure, 'push');
            app.NOButton.ButtonPushedFcn = createCallbackFcn(app, @NOButtonPushed, true);
            app.NOButton.Text = 'NO';
            app.IfyesenternewamountsLabel = uilabel(app.UIFigure);
            app.IfyesenternewamountsLabel.Text = 'If yes, enter new amounts';
            app.ADA8Label = uilabel(app.UIFigure);
            app.ADA8Label.Text = '#45ADA8';
            app.de0adLabel = uilabel(app.UIFigure);
            app.de0adLabel.Text = '#9de0ad';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'#547980'; ''};
            app.F4FLabel = uilabel(app.UIFigure);
            app.F4FLabel.Text = {'#594F4F'; ''};
            app.E5FCC2Label = uilabel(app.UIFigure);
            app.E5FCC2Label.Text = '#E5FCC2';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Icon = 'ESTOP.png';
            app.ESTOPButton.Text = {'ESTOP'; ''};
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Value = 50;
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = {'Joint 2'; ''};
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Value = 50;
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = {'Joint 3'; ''};
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Value = 50;
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = {'Joint 4'; ''};
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Value = 50;
            app.WhenthelightisgreenyoucanchangethepositionoftherobotLabel = uilabel(app.UIFigure);
            app.WhenthelightisgreenyoucanchangethepositionoftherobotLabel.Text = 'When the light is green, you can change the position of the robot:';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Value = 50;
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Value = 50;
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Value = 50;
            app.JointmovementsLabel = uilabel(app.UIFigure);
            app.JointmovementsLabel.Text = 'Joint movements:';
            app.PlanarmovementsLabel = uilabel(app.UIFigure);
            app.PlanarmovementsLabel.Text = 'Planar movements:';
            app.UserDropDownLabel = uilabel(app.UIFigure);
            app.UserDropDownLabel.Text = 'User';
            app.UserDropDown = uidropdown(app.UIFigure);
            app.UserDropDown.Items = {'Steph', 'Hannah'};
            app.UserDropDown.ValueChangedFcn = createCallbackFcn(app, @UserDropDownValueChanged, true);
            app.UserDropDown.Value = 'Steph';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.WelcomeLabel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A2app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end