classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        MedicationadminTab          matlab.ui.container.Tab
        OrangeSpinner               matlab.ui.control.Spinner
        OrangeSpinnerLabel          matlab.ui.control.Label
        PurpleSpinner               matlab.ui.control.Spinner
        PurpleSpinnerLabel          matlab.ui.control.Label
        GreenSpinner                matlab.ui.control.Spinner
        GreenSpinnerLabel           matlab.ui.control.Label
        StartOrangeButton           matlab.ui.control.Button
        StartPurpleButton           matlab.ui.control.Button
        StartGreenButton            matlab.ui.control.Button
        ManualControlTab            matlab.ui.container.Tab
        Joint5Slider                matlab.ui.control.Slider
        Joint5SliderLabel           matlab.ui.control.Label
        ZSlider                     matlab.ui.control.Slider
        ZSliderLabel                matlab.ui.control.Label
        YSlider                     matlab.ui.control.Slider
        YSliderLabel                matlab.ui.control.Label
        XSlider                     matlab.ui.control.Slider
        XSliderLabel                matlab.ui.control.Label
        Joint4Slider                matlab.ui.control.Slider
        Joint4SliderLabel           matlab.ui.control.Label
        Joint3Slider                matlab.ui.control.Slider
        Joint3SliderLabel           matlab.ui.control.Label
        Joint2Slider                matlab.ui.control.Slider
        Joint2SliderLabel           matlab.ui.control.Label
        Joint1Slider                matlab.ui.control.Slider
        Joint1SliderLabel           matlab.ui.control.Label
        PlanarmovementsLabel        matlab.ui.control.Label
        JointmovementsLabel         matlab.ui.control.Label
        ESTOPRESUMEButton           matlab.ui.control.StateButton
        Label                       matlab.ui.control.Label
        WelcomeLabel                matlab.ui.control.Label
        CloseButton                 matlab.ui.control.Button
        EStopButton                 matlab.ui.control.Button
        CollisiondetectionCheckBox  matlab.ui.control.CheckBox
    end


    properties (Access = private)
        valueresume
    end

    methods (Access = private)

        function results = func(app)

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            figure();
            global begin
            begin = 0;
            global beginpurple
            beginpurple = 0;
            global beginorange
            beginorange = 0;


        end

        % Value changed function: GreenSpinner
        function GreenSpinnerValueChanged(app, event)
            value = app.GreenSpinner.Value;
            disp('GS');
            global begin;
            if begin == 1
                if value == 1
                    disp('1 Green');
                    LabAssignment2(1);

                end

                if value == 2
                    disp('2 Green');
                    LabAssignment2(2);

                end

                if value == 3
                    disp('3 Green');
                    LabAssignment2(3);

                end

            end
        end

        % Value changed function: PurpleSpinner
        function PurpleSpinnerValueChanged(app, event)
            value = app.PurpleSpinner.Value;
            disp('PS');
            global beginpurple
            if beginpurple == 1

                if value == 1
                    disp('1 Purple');
                    LabAssignment2(4);

                end

                if value == 2
                    disp('2 Purple');
                    LabAssignment2(5);

                end

                if value == 3
                    disp('3 Purple');
                    LabAssignment2(6);

                end
            end
        end

        % Value changed function: OrangeSpinner
        function OrangeSpinnerValueChanged(app, event)
            value = app.OrangeSpinner.Value;

            disp('OS');
            global beginorange
            if beginorange == 1
                if value == 1
                    disp('1 Orange');
                    LabAssignment2(7);

                end

                if value == 2
                    disp('2 Orange');
                    LabAssignment2(8);

                end

                if value == 3
                    disp('3 Orange');
                    LabAssignment2(8);

                end
            end

        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            disp('E-stop pressed');
            c = true;
            while c
                pause(0.1);
                if app.ESTOPRESUMEButton.Value
                    app.ESTOPRESUMEButton.Value = false;
                    c = false;
                end
            end

        end

        % Value changed function: CollisiondetectionCheckBox
        function CollisiondetectionCheckBoxValueChanged(app, event)
           disp("Collision Detection")
            value = app.CollisiondetectionCheckBox.Value;
            if value ==1
                CollisionDetection();
            end
        end

        % Button pushed function: StartGreenButton
        function GreenStartButtonPushed(app, event)
            global begin
            begin = 1;
            GreenSpinnerValueChanged(app);
        end

        % Button pushed function: StartPurpleButton
        function StartPurpleButtonPushed(app, event)
            global beginpurple
            beginpurple = 1;
            PurpleSpinnerValueChanged(app);
        end

        % Button pushed function: StartOrangeButton
        function StartOrangeButtonPushed(app, event)
            global beginorange
            beginorange = 1;
            OrangeSpinnerValueChanged(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
%             delete(figure)
%             delete(app)
            close all hidden
        end

        % Value changed function: ESTOPRESUMEButton
        function ESTOPRESUMEButtonValueChanged(app, event)
            disp("ESTOP Resume");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CollisiondetectionCheckBox = uicheckbox(app.UIFigure);
            app.CollisiondetectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @CollisiondetectionCheckBoxValueChanged, true);
            app.CollisiondetectionCheckBox.Text = 'Collision detection';
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Icon = 'ESTOP.png';
            app.EStopButton.Text = 'E-Stop';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.WelcomeLabel = uilabel(app.UIFigure);
            app.WelcomeLabel.Text = 'Welcome';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ESTOPRESUMEButton = uibutton(app.UIFigure, 'state');
            app.ESTOPRESUMEButton.ValueChangedFcn = createCallbackFcn(app, @ESTOPRESUMEButtonValueChanged, true);
            app.ESTOPRESUMEButton.Text = 'ESTOP RESUME';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MedicationadminTab = uitab(app.TabGroup);
            app.MedicationadminTab.Title = 'Medication admin';
            app.StartGreenButton = uibutton(app.MedicationadminTab, 'push');
            app.StartGreenButton.ButtonPushedFcn = createCallbackFcn(app, @GreenStartButtonPushed, true);
            app.StartGreenButton.Text = 'Start Green';
            app.StartPurpleButton = uibutton(app.MedicationadminTab, 'push');
            app.StartPurpleButton.ButtonPushedFcn = createCallbackFcn(app, @StartPurpleButtonPushed, true);
            app.StartPurpleButton.Text = 'Start Purple';
            app.StartOrangeButton = uibutton(app.MedicationadminTab, 'push');
            app.StartOrangeButton.ButtonPushedFcn = createCallbackFcn(app, @StartOrangeButtonPushed, true);
            app.StartOrangeButton.Text = 'Start Orange';
            app.GreenSpinnerLabel = uilabel(app.MedicationadminTab);
            app.GreenSpinnerLabel.Text = 'Green';
            app.GreenSpinner = uispinner(app.MedicationadminTab);
            app.GreenSpinner.Limits = [0 3];
            app.GreenSpinner.ValueChangedFcn = createCallbackFcn(app, @GreenSpinnerValueChanged, true);
            app.PurpleSpinnerLabel = uilabel(app.MedicationadminTab);
            app.PurpleSpinnerLabel.Text = 'Purple';
            app.PurpleSpinner = uispinner(app.MedicationadminTab);
            app.PurpleSpinner.Limits = [0 3];
            app.PurpleSpinner.ValueChangedFcn = createCallbackFcn(app, @PurpleSpinnerValueChanged, true);
            app.OrangeSpinnerLabel = uilabel(app.MedicationadminTab);
            app.OrangeSpinnerLabel.Text = 'Orange';
            app.OrangeSpinner = uispinner(app.MedicationadminTab);
            app.OrangeSpinner.Limits = [0 3];
            app.OrangeSpinner.ValueChangedFcn = createCallbackFcn(app, @OrangeSpinnerValueChanged, true);
            app.ManualControlTab = uitab(app.TabGroup);
            app.ManualControlTab.Title = 'Manual Control';
            app.JointmovementsLabel = uilabel(app.ManualControlTab);
            app.JointmovementsLabel.Text = 'Joint movements:';
            app.PlanarmovementsLabel = uilabel(app.ManualControlTab);
            app.PlanarmovementsLabel.Text = 'Planar movements:';
            app.Joint1SliderLabel = uilabel(app.ManualControlTab);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.ManualControlTab);
            app.Joint1Slider.Value = 50;
            app.Joint2SliderLabel = uilabel(app.ManualControlTab);
            app.Joint2SliderLabel.Text = {'Joint 2'; ''};
            app.Joint2Slider = uislider(app.ManualControlTab);
            app.Joint2Slider.Value = 50;
            app.Joint3SliderLabel = uilabel(app.ManualControlTab);
            app.Joint3SliderLabel.Text = {'Joint 3'; ''};
            app.Joint3Slider = uislider(app.ManualControlTab);
            app.Joint3Slider.Value = 50;
            app.Joint4SliderLabel = uilabel(app.ManualControlTab);
            app.Joint4SliderLabel.Text = {'Joint 4'; ''};
            app.Joint4Slider = uislider(app.ManualControlTab);
            app.Joint4Slider.Value = 50;
            app.XSliderLabel = uilabel(app.ManualControlTab);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.ManualControlTab);
            app.XSlider.Value = 50;
            app.YSliderLabel = uilabel(app.ManualControlTab);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.ManualControlTab);
            app.YSlider.Value = 50;
            app.ZSliderLabel = uilabel(app.ManualControlTab);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.ManualControlTab);
            app.ZSlider.Value = 50;
            app.Joint5SliderLabel = uilabel(app.ManualControlTab);
            app.Joint5SliderLabel.Text = {'Joint 5'; ''};
            app.Joint5Slider = uislider(app.ManualControlTab);
            app.Joint5Slider.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end